classdef CS222_ImageFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ByCaidenPettyLabel            matlab.ui.control.Label
        CS222ImageprocessingappLabel  matlab.ui.control.Label
        ApplyButton                   matlab.ui.control.Button
        ChoosefilterDropDown          matlab.ui.control.DropDown
        ChoosefilterDropDownLabel     matlab.ui.control.Label
        LoadimageButton               matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        InputImage;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            % Read name of image selected & get path
            [fname, pname]=uigetfile('*.*');

            % Read image
            app.InputImage = imread(fname);

            % Display image
            imshow(app.InputImage, 'parent', app.UIAxes);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            UserSelection = app.ChoosefilterDropDown.Value;
            if(UserSelection == "Low Pass Filter")
                filter1 = fspecial("average");
                outImage1 = imfilter(app.InputImage, filter1);
                imshow(outImage1, 'Parent', app.UIAxes_2);

            elseif(UserSelection == "Gaussian")
                filter2 = fspecial("gaussian");
                outImage2 = imfilter(app.InputImage, filter2);
                imshow(outImage2, 'Parent', app.UIAxes_2);

            elseif(UserSelection == "Laplacian")
                filter3 = fspecial("laplacian");
                outImage3 = imfilter(app.InputImage, filter3);
                imshow(outImage3, 'Parent', app.UIAxes_2);

            elseif(UserSelection == "Sobel")
                filter4 = fspecial("sobel");
                outImage4 = imfilter(app.InputImage, filter4);
                imshow(outImage4, 'Parent', app.UIAxes_2);

            elseif(UserSelection == "Disk")
                filter5 = fspecial("disk");
                outImage5 = imfilter(app.InputImage, filter5);
                imshow(outImage5, 'Parent', app.UIAxes_2);

            elseif(UserSelection == "Motion")
                filter6 = fspecial("motion");
                outImage6 = imfilter(app.InputImage, filter6);
                imshow(outImage6, 'Parent', app.UIAxes_2);

            elseif(UserSelection == "Prewitt")
                filter7 = fspecial("prewitt");
                outImage7 = imfilter(app.InputImage, filter7);
                imshow(outImage7, 'Parent', app.UIAxes_2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Tooltip = {'Select an image'};
            app.LoadimageButton.Text = 'Load image';
            app.ChoosefilterDropDownLabel = uilabel(app.UIFigure);
            app.ChoosefilterDropDownLabel.Text = 'Choose filter';
            app.ChoosefilterDropDown = uidropdown(app.UIFigure);
            app.ChoosefilterDropDown.Items = {'Low Pass Filter', 'Gaussian', 'Laplacian', 'Sobel', 'Disk', 'Motion', 'Prewitt'};
            app.ChoosefilterDropDown.Tooltip = {'Filter selected image'};
            app.ChoosefilterDropDown.Value = 'Low Pass Filter';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Tooltip = {'Apply image'};
            app.ApplyButton.Text = 'Apply';
            app.CS222ImageprocessingappLabel = uilabel(app.UIFigure);
            app.CS222ImageprocessingappLabel.Text = 'CS222 Image processing app';
            app.ByCaidenPettyLabel = uilabel(app.UIFigure);
            app.ByCaidenPettyLabel.Text = 'By Caiden Petty';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CS222_ImageFilterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end